classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        EditField_time     matlab.ui.control.EditField
        Label_5            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Tab_parameter      matlab.ui.control.Table
        Label_parameter    matlab.ui.control.Label
        Tab_init           matlab.ui.control.Table
        Label_init         matlab.ui.control.Label
        SimulateButton     matlab.ui.control.Button
        Spinner_node       matlab.ui.control.Spinner
        Label_3            matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        ax_velocity        matlab.ui.control.UIAxes
        ax_theta           matlab.ui.control.UIAxes
        ax_graph           matlab.ui.control.UIAxes
        Panel              matlab.ui.container.Panel
        SpeedSpinner       matlab.ui.control.Spinner
        SpeedSpinnerLabel  matlab.ui.control.Label
        Slider_time        matlab.ui.control.Slider
        Label_6            matlab.ui.control.Label
        PlayButton         matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Dynamics        % 多重振り子の微分方程式を司るクラスを格納（dynamics.m）
        Graph           % Graphプロットのクラスを格納
        plt_theta   ={} % 「角度」の応答プロットのクラス配列を格納
        plt_velocity={} % 「角速度」の応答プロットのクラス配列を格納

        result_t        % ODEソルバーで解いた応答データの時間インデックスを格納
        result_x        % ODEソルバーで解いた状態の応答データを格納

        current_tidx    % アニメーション再生の際の現在の時刻を保存
        play_now        % アニメーションが現在再生中かどうかのフラッグ
    end
    
    methods (Access = private)

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % パラメータ設定用のテーブルのフォーマット %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function set_tab(app)
            app.Tab_parameter.RowName    = "Node" + (1:app.Dynamics.num);
            app.Tab_parameter.Data       = [app.Dynamics.m_vec(:),app.Dynamics.L_vec(:)];
            app.Tab_init.RowName    = "Node" + (1:app.Dynamics.num);
            app.Tab_init.Data       = zeros(app.Dynamics.num,2);
        end

    %% グラフィックの設定編

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % グラフィック全般の初期設定をまとめて行うメソッド %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function set_graph(app)
            % 応答プロットの初期設定
            app.set_plot;

            % グラフプロットの初期設定
            colormap(app.ax_graph,'turbo')
            app.Graph = app.Dynamics.plot(app.ax_graph);
            app.set_position( app.Tab_init.Data(:,1) )
            app.set_MarkerSize;
        end
        

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 応答プロットの初期設定を行うメソッド %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function set_plot(app)
            
            myplt(app.ax_theta,'角度')
            myplt(app.ax_velocity,'角速度')
            
            legend(app.ax_theta,[],'Location','eastoutside')

            function myplt(ax,ylab)
                hold(ax,'on')
                grid(ax,'on')
                xlabel(ax,'時間(s)')
                ylabel(ax,ylab)
            end
        end

       
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % グラフプロットの初期設定（ノードサイズ） %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function set_MarkerSize(app)
            m_vec = app.Dynamics.m_vec;
            scale = 40/max(m_vec) / sqrt(app.Dynamics.num);
            app.Graph.MarkerSize = [1; scale * m_vec(:)];
        end


    %% アニメーションの設定編

        %%%%%%%%%%%%%%%%%%%%%
        % アニメーションの再生 %
        %%%%%%%%%%%%%%%%%%%%%
        function play(app)
            while app.play_now && app.current_tidx <= numel(app.result_t)
                app.refresh_plot
                if app.current_tidx == numel(app.result_t)
                    app.PlayButtonPushed;
                else
                    app.current_tidx = app.current_tidx+app.SpeedSpinner.Value;
                end
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 角度データからグラフプロットを更新 %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function set_position(app,theta)
            app.Graph.XData(1) = 0;
            app.Graph.YData(1) = 0;
            for i = 1:app.Dynamics.num
                app.Graph.XData(i+1) = app.Graph.XData(i) + app.Dynamics.L_vec(i) * sin(theta(i));
                app.Graph.YData(i+1) = app.Graph.YData(i) - app.Dynamics.L_vec(i) * cos(theta(i));
            end
            ylim(app.ax_graph, app.Graph.YData(1)+[-sum(app.Dynamics.L_vec),0])
            xlim(app.ax_graph, app.Graph.YData(1)+0.9*sum(app.Dynamics.L_vec)*[-1,1])
        end


        %%%%%%%%%%%%%%%%%%%
        % 応答プロットを更新 %
        %%%%%%%%%%%%%%%%%%%
        function refresh_plot(app)
                tnow = app.result_t(app.current_tidx);

                n = app.Dynamics.num;
                title(app.ax_graph,['Time : ',num2str(tnow,'%.2f'),'(s)'],'FontSize',20)
                theta = app.result_x(1:n,app.current_tidx);
                app.set_position(theta)

                xlim(app.ax_theta   , tnow+[-5,0])
                xlim(app.ax_velocity, tnow+[-5,0])

                drawnow

                app.Slider_time.Value = tnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Dynamics = pendulum.dynamics(app.Spinner_node.Value);
            app.set_tab
            app.set_graph
            xlabel(app.ax_graph,'')
            ylabel(app.ax_graph,'')
            axis(app.ax_graph,'off')
            daspect(app.ax_graph,[1 1 1])
        end

        % Value changed function: Spinner_node
        function Spinner_nodeValueChanged(app, event)
            value = app.Spinner_node.Value;
            app.Dynamics.num = value;
            app.set_tab
            app.set_graph

            cellfun(@(p) delete(p), app.plt_theta)
            cellfun(@(p) delete(p), app.plt_velocity)
            app.PlayButton.Enable = false;
            app.PlayButton.BackgroundColor = [0.5,0.5,0.5];
            
        end

        % Cell edit callback: Tab_parameter
        function Tab_parameterCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            i = indices(1);
            if indices(2) ==1
                app.Dynamics.m_vec(i) = newData;
                app.set_MarkerSize();
            else
                app.Dynamics.L_vec(i) = newData;
                app.set_position(app.Tab_init.Data(:,1))
            end

            cellfun(@(p) delete(p), app.plt_theta)
            cellfun(@(p) delete(p), app.plt_velocity)
            app.PlayButton.Enable = false;
            app.PlayButton.BackgroundColor = [0.5,0.5,0.5];

        end

        % Cell edit callback: Tab_init
        function Tab_initCellEdit(app, event)
            indices = event.Indices;
            
            if indices(2)==1
                theta = app.Tab_init.Data(:,1);
                app.set_position(theta);
            end

            cellfun(@(p) delete(p), app.plt_theta)
            cellfun(@(p) delete(p), app.plt_velocity)
            app.PlayButton.Enable = false;
            app.PlayButton.BackgroundColor = [0.5,0.5,0.5];

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.PlayButton.Enable = false;
            app.PlayButton.BackgroundColor = [0.5,0.5,0.5];
            app.set_graph

            % 微分方程式の求解
                init = app.Tab_init.Data;
                func = @(t,x) app.Dynamics.dx(x);
                [t,y] = ode45(func,[0,str2double(app.EditField_time.Value)],init(:));

            % 応答プロット
                cellfun(@(p) delete(p), app.plt_theta)
                cellfun(@(p) delete(p), app.plt_velocity)

                cdata = app.Graph.NodeColor(2:end,:);
                opt   = {'LineWidth',1.5,'Color'};
                n = app.Dynamics.num;

                app.plt_theta    = arrayfun(@(idx) {plot(app.ax_theta   ,t,y(:,idx  ),opt{:},cdata(idx,:))}, 1:n);
                app.plt_velocity = arrayfun(@(idx) {plot(app.ax_velocity,t,y(:,idx+n),opt{:},cdata(idx,:))}, 1:n);

                [lmin,lmax] = bounds(y(:,1:n),'all');
                ylim(app.ax_theta, [lmin-0.01,lmax+0.01])

                [lmin,lmax] = bounds(y(:,n+(1:n)),'all');
                ylim(app.ax_velocity, [lmin-0.01,lmax+0.01])

                legend(app.ax_theta,"Node"+(1:n),'Location','eastoutside')


            % 応答データのリサンプリング
                fps = 100;
                t_ = t(1):1/fps:t(end);
                y_ = zeros(size(y,2),numel(t_));
                for  i = 1:size(y,2)
                    y_(i,:) = reshape( pchip(t,y(:,i).',t_) ,1,[]);
                end
                app.result_t = t_;
                app.result_x = y_;
                app.current_tidx = 1;

            % Playボタンを使用可能にする
                app.PlayButton.Enable = true;
                app.PlayButtonPushed([])

        end

        % Value changed function: EditField_time
        function EditField_timeValueChanged(app, event)
            value = app.EditField_time.Value;
            value = str2double(value);
            if isnan(value)
                app.EditField_time.Value = num2str(event.PreviousValue);
                return
            end

            app.Slider_time.Limits = [0,value];
            xlim(app.ax_theta,[0,value]);
            xlim(app.ax_velocity,[0,value]);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if app.play_now
                app.play_now = false;
                app.PlayButton.BackgroundColor = [0.39,0.83,0.07];
                app.PlayButton.Text = 'Start';
                app.Graph.NodeLabel = ["", "m"+(1:app.Dynamics.num)];
                app.Graph.EdgeLabel = "L"+(1:app.Dynamics.num);

                app.Tab_parameter.Enable = 'on';
                app.Tab_init.Enable      = 'on';
                app.Spinner_node.Enable  = 'on';
            else
                app.play_now = true;
                app.PlayButton.BackgroundColor = [0.85,0.33,0.10];
                app.PlayButton.Text = 'Stop';
                app.Graph.NodeLabel = repmat("",1,app.Dynamics.num+1);
                app.Graph.EdgeLabel = repmat("",1,app.Dynamics.num  );

                app.Tab_parameter.Enable = 'off';
                app.Tab_init.Enable      = 'off';
                app.Spinner_node.Enable  = 'off';

                if app.current_tidx > numel(app.result_t)
                    app.current_tidx = 1;
                end
                app.play;
            end
        end

        % Value changed function: Slider_time
        function Slider_timeValueChanged(app, event)
            value = app.Slider_time.Value;
            [~,idx] = min(abs(app.result_t-value));
            app.current_tidx = idx;
            app.refresh_plot;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {534, 534};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = 'ノード数';
            app.Spinner_node = uispinner(app.LeftPanel);
            app.Spinner_node.Limits = [1 Inf];
            app.Spinner_node.ValueChangedFcn = createCallbackFcn(app, @Spinner_nodeValueChanged, true);
            app.Spinner_node.Value = 1;
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Label_init = uilabel(app.LeftPanel);
            app.Label_init.Text = '初期値';
            app.Tab_init = uitable(app.LeftPanel);
            app.Tab_init.ColumnName = {'theta'; 'velocity'};
            app.Tab_init.RowName = {};
            app.Tab_init.ColumnEditable = [true true];
            app.Tab_init.CellEditCallback = createCallbackFcn(app, @Tab_initCellEdit, true);
            app.Label_parameter = uilabel(app.LeftPanel);
            app.Label_parameter.Text = 'パラメータ';
            app.Tab_parameter = uitable(app.LeftPanel);
            app.Tab_parameter.ColumnName = {'m(質量)'; 'L(紐の長さ)'};
            app.Tab_parameter.RowName = {};
            app.Tab_parameter.ColumnEditable = [true true];
            app.Tab_parameter.CellEditCallback = createCallbackFcn(app, @Tab_parameterCellEdit, true);
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '振り子';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = 'シミュレーション時間(s)';
            app.EditField_time = uieditfield(app.LeftPanel, 'text');
            app.EditField_time.ValueChangedFcn = createCallbackFcn(app, @EditField_timeValueChanged, true);
            app.EditField_time.Value = '60';
            app.RightPanel = uipanel(app.GridLayout);
            app.ax_graph = uiaxes(app.RightPanel);
            title(app.ax_graph, 'Title')
            xlabel(app.ax_graph, 'X')
            ylabel(app.ax_graph, 'Y')
            zlabel(app.ax_graph, 'Z')
            app.ax_theta = uiaxes(app.RightPanel);
            title(app.ax_theta, 'Title')
            xlabel(app.ax_theta, 'X')
            ylabel(app.ax_theta, 'Y')
            zlabel(app.ax_theta, 'Z')
            app.ax_velocity = uiaxes(app.RightPanel);
            title(app.ax_velocity, 'Title')
            xlabel(app.ax_velocity, 'X')
            ylabel(app.ax_velocity, 'Y')
            zlabel(app.ax_velocity, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '時間(s)';
            app.Slider_time = uislider(app.Panel);
            app.Slider_time.Limits = [0 60];
            app.Slider_time.ValueChangedFcn = createCallbackFcn(app, @Slider_timeValueChanged, true);
            app.SpeedSpinnerLabel = uilabel(app.Panel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.Panel);
            app.SpeedSpinner.Step = 5;
            app.SpeedSpinner.Limits = [1 200];
            app.SpeedSpinner.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end